classdef ANC300_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        Voltage_3EditField    matlab.ui.control.NumericEditField
        Voltage_2EditField    matlab.ui.control.NumericEditField
        Voltage_1EditField    matlab.ui.control.NumericEditField
        Frequency_3EditField  matlab.ui.control.NumericEditField
        Frequency_2EditField  matlab.ui.control.NumericEditField
        Frequency_1EditField  matlab.ui.control.NumericEditField
        UpdateButton          matlab.ui.control.Button
        ModeLabel             matlab.ui.control.Label
        Mode_3DropDown        matlab.ui.control.DropDown
        Mode_2DropDown        matlab.ui.control.DropDown
        Mode_1DropDown        matlab.ui.control.DropDown
        VoltageVLabel         matlab.ui.control.Label
        FrequencyHzLabel      matlab.ui.control.Label
        ZAxisLabel            matlab.ui.control.Label
        YAxisLabel            matlab.ui.control.Label
        XAxisLabel            matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        OutXLamp              matlab.ui.control.Lamp
        OutXLampLabel         matlab.ui.control.Label
        OutYLamp              matlab.ui.control.Lamp
        OutYLampLabel         matlab.ui.control.Label
        OutZLamp              matlab.ui.control.Lamp
        OutZLampLabel         matlab.ui.control.Label
        StepdownXButton_2     matlab.ui.control.Button
        StepupXButton_2       matlab.ui.control.Button
        StepdownYButton_2     matlab.ui.control.Button
        StepupYButton_2       matlab.ui.control.Button
        StepdownZButton_2     matlab.ui.control.Button
        StepupZButton_2       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
       device
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.OutXLamp.Color = 'red';
            app.OutYLamp.Color = 'red';
            app.OutZLamp.Color = 'red';
            app.device = ANC300(port);
        end

        % Button pushed function: StepupZButton_2
        function StepupZButton_2Pushed(app, event)
            app.OutZLamp.Color = 'green';
            app.device.stepup(1,3);
            pause(0.20)
            app.OutZLamp.Color = 'red';
        end

        % Button pushed function: StepdownZButton_2
        function StepdownZButton_2Pushed(app, event)
            app.OutZLamp.Color = 'green';
            app.device.stepdown(1,3);
            pause(0.20)
            app.OutZLamp.Color = 'red';
        end

        % Button pushed function: StepupYButton_2
        function StepupYButton_2Pushed(app, event)
            app.OutYLamp.Color = 'green';
            app.device.stepup(1,2);
            pause(0.20)
            app.OutYLamp.Color = 'red';
        end

        % Button pushed function: StepdownYButton_2
        function StepdownYButton_2Pushed(app, event)
            app.OutYLamp.Color = 'green';
            app.device.stepdown(1,2);
            pause(0.20)
            app.OutYLamp.Color = 'red';
        end

        % Button pushed function: StepupXButton_2
        function StepupXButton_2Pushed(app, event)
            app.OutXLamp.Color = 'green';
            app.device.stepup(1,1);
            pause(0.20)
            app.OutXLamp.Color = 'red';
        end

        % Button pushed function: StepdownXButton_2
        function StepdownXButton_2Pushed(app, event)
            app.OutXLamp.Color = 'green';
            app.device.stepdown(1,1);
            pause(0.20)
            app.OutXLamp.Color = 'red';
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.device.set_frequency(app.Frequency_1EditField.Value,1);
            app.device.set_voltage(app.Voltage_1EditField.Value,1);
            app.device.set_frequency(app.Frequency_2EditField.Value,2);
            app.device.set_voltage(app.Voltage_2EditField.Value,2);
            app.device.set_frequency(app.Frequency_3EditField.Value,3);
            app.device.set_voltage(app.Voltage_3EditField.Value,3);
           
            switch app.Mode_1DropDown.Value % axis mode text gnd, inp, cap, stp, off, stp+, stp-
                case 'Gnd'
                    app.device.set_mode('gnd',1);
                case 'inp'
                    app.device.set_mode('inp',1);
                case 'cap'
                    app.device.set_mode('cap',1);
                case 'stp'
                    app.device.set_mode('stp',1);
                case 'off'
                    app.device.set_mode('off',1);
                case 'stp+'
                    app.device.set_mode('stp+',1);
                case 'stp-'
                    app.device.set_mode('stp-',1);
            end
            switch app.Mode_2DropDown.Value % axis mode text gnd, inp, cap, stp, off, stp+, stp-
                case 'Gnd'
                    app.device.set_mode('gnd',2);
                case 'inp'
                    app.device.set_mode('inp',2);
                case 'cap'
                    app.device.set_mode('cap',2);
                case 'stp'
                    app.device.set_mode('stp',2);
                case 'off'
                    app.device.set_mode('off',2);
                case 'stp+'
                    app.device.set_mode('stp+',2);
                case 'stp-'
                    app.device.set_mode('stp-',2);
            end
            switch app.Mode_3DropDown.Value % axis mode text gnd, inp, cap, stp, off, stp+, stp-
                case 'Gnd'
                    app.device.set_mode('gnd',3);
                case 'inp'
                    app.device.set_mode('inp',3);
                case 'cap'
                    app.device.set_mode('cap',3);
                case 'stp'
                    app.device.set_mode('stp',3);
                case 'off'
                    app.device.set_mode('off',3);
                case 'stp+'
                    app.device.set_mode('stp+',3);
                case 'stp-'
                    app.device.set_mode('stp-',3);
            end
        end

        % Drop down opening function: Mode_3DropDown
        function Mode_3DropDownOpening(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {216, 216};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {472, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XAxisLabel = uilabel(app.LeftPanel);
            app.XAxisLabel.Text = 'X-Axis';
            app.YAxisLabel = uilabel(app.LeftPanel);
            app.YAxisLabel.Text = 'Y-Axis';
            app.ZAxisLabel = uilabel(app.LeftPanel);
            app.ZAxisLabel.Text = 'Z-Axis';
            app.FrequencyHzLabel = uilabel(app.LeftPanel);
            app.FrequencyHzLabel.Text = 'Frequency(Hz)';
            app.VoltageVLabel = uilabel(app.LeftPanel);
            app.VoltageVLabel.Text = 'Voltage(V)';
            app.Mode_1DropDown = uidropdown(app.LeftPanel);
            app.Mode_1DropDown.Items = {'Gnd', 'inp', 'cap', 'stp', 'off', 'stp+', 'stp-'};
            app.Mode_1DropDown.Value = 'stp';
            app.Mode_2DropDown = uidropdown(app.LeftPanel);
            app.Mode_2DropDown.Items = {'Gnd', 'inp', 'cap', 'stp', 'off', 'stp+', 'stp-'};
            app.Mode_2DropDown.Value = 'stp';
            app.Mode_3DropDown = uidropdown(app.LeftPanel);
            app.Mode_3DropDown.Items = {'Gnd', 'inp', 'cap', 'stp', 'off', 'stp+', 'stp-'};
            app.Mode_3DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Mode_3DropDownOpening, true);
            app.Mode_3DropDown.Value = 'stp';
            app.ModeLabel = uilabel(app.LeftPanel);
            app.ModeLabel.Text = 'Mode';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Frequency_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency_1EditField.Value = 500;
            app.Frequency_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency_2EditField.Value = 550;
            app.Frequency_3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency_3EditField.Value = 500;
            app.Voltage_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Voltage_1EditField.Value = 35;
            app.Voltage_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Voltage_2EditField.Value = 35;
            app.Voltage_3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Voltage_3EditField.Limits = [0 Inf];
            app.Voltage_3EditField.ValueDisplayFormat = '%d';
            app.Voltage_3EditField.Value = 35;
            app.RightPanel = uipanel(app.GridLayout);
            app.StepupZButton_2 = uibutton(app.RightPanel, 'push');
            app.StepupZButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepupZButton_2Pushed, true);
            app.StepupZButton_2.Text = 'Step up Z';
            app.StepdownZButton_2 = uibutton(app.RightPanel, 'push');
            app.StepdownZButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepdownZButton_2Pushed, true);
            app.StepdownZButton_2.Text = 'Step down Z';
            app.StepupYButton_2 = uibutton(app.RightPanel, 'push');
            app.StepupYButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepupYButton_2Pushed, true);
            app.StepupYButton_2.Text = 'Step up Y';
            app.StepdownYButton_2 = uibutton(app.RightPanel, 'push');
            app.StepdownYButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepdownYButton_2Pushed, true);
            app.StepdownYButton_2.Text = 'Step down Y';
            app.StepupXButton_2 = uibutton(app.RightPanel, 'push');
            app.StepupXButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepupXButton_2Pushed, true);
            app.StepupXButton_2.Text = 'Step up X';
            app.StepdownXButton_2 = uibutton(app.RightPanel, 'push');
            app.StepdownXButton_2.ButtonPushedFcn = createCallbackFcn(app, @StepdownXButton_2Pushed, true);
            app.StepdownXButton_2.Text = 'Step down X';
            app.OutZLampLabel = uilabel(app.RightPanel);
            app.OutZLampLabel.Text = 'Out Z';
            app.OutZLamp = uilamp(app.RightPanel);
            app.OutYLampLabel = uilabel(app.RightPanel);
            app.OutYLampLabel.Text = 'Out Y';
            app.OutYLamp = uilamp(app.RightPanel);
            app.OutXLampLabel = uilabel(app.RightPanel);
            app.OutXLampLabel.Text = 'Out X';
            app.OutXLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ANC300_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end